classdef yaw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label15                       matlab.ui.control.Label
        Label14                       matlab.ui.control.Label
        ycomponentairmsLabel          matlab.ui.control.Label
        xcomponentairmsLabel          matlab.ui.control.Label
        InletInputsLabel              matlab.ui.control.Label
        carvelocitymsEditField        matlab.ui.control.NumericEditField
        carvelocitymsEditFieldLabel   matlab.ui.control.Label
        airangleEditField             matlab.ui.control.NumericEditField
        airangleEditFieldLabel        matlab.ui.control.Label
        CarDropDown                   matlab.ui.control.DropDown
        CarDropDownLabel              matlab.ui.control.Label
        Label13                       matlab.ui.control.Label
        Label12                       matlab.ui.control.Label
        Label11                       matlab.ui.control.Label
        zmLabel_4                     matlab.ui.control.Label
        ymLabel_4                     matlab.ui.control.Label
        xmLabel_4                     matlab.ui.control.Label
        RotationAxisDirectionLabel_2  matlab.ui.control.Label
        Label10                       matlab.ui.control.Label
        Label9                        matlab.ui.control.Label
        Label8                        matlab.ui.control.Label
        zmLabel_3                     matlab.ui.control.Label
        ymLabel_3                     matlab.ui.control.Label
        xmLabel_3                     matlab.ui.control.Label
        RotationAxisOriginLabel_2     matlab.ui.control.Label
        RearwheelLabel                matlab.ui.control.Label
        Label7                        matlab.ui.control.Label
        Label6                        matlab.ui.control.Label
        Label5                        matlab.ui.control.Label
        zmLabel_2                     matlab.ui.control.Label
        ymLabel_2                     matlab.ui.control.Label
        xmLabel_2                     matlab.ui.control.Label
        RotationAxisDirectionLabel    matlab.ui.control.Label
        Label4                        matlab.ui.control.Label
        Label3                        matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        zmLabel                       matlab.ui.control.Label
        ymLabel                       matlab.ui.control.Label
        xmLabel                       matlab.ui.control.Label
        BacktomainmenuButton          matlab.ui.control.Button
        RotationAxisOriginLabel       matlab.ui.control.Label
        FrontwheelLabel               matlab.ui.control.Label
        WheelsLabel                   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        WheelangularspeedradsLabel    matlab.ui.control.Label
        calculatesetupButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculatesetupButton
        function calculatesetupButtonPushed(app, event)
            %wheel rotation calculation 
            %rotational velocity = velocity/diameter
            if app.carvelocitymsEditField.Value ~= 0
                app.Label.Visible = "on";
                velocity = app.carvelocitymsEditField.Value;
                rotational_velocity = round(-velocity/0.235,2);
                app.Label.Text = string(rotational_velocity);
            end
            
            if app.CarDropDown.Value ~= "<select car>"

                %output Labels activation
                app.Label2.Visible = "on";
                app.Label3.Visible = "on";
                app.Label4.Visible = "on";
                app.Label5.Visible = "on";
                app.Label6.Visible = "on";
                app.Label7.Visible = "on";
                app.Label8.Visible = "on";
                app.Label9.Visible = "on";
                app.Label10.Visible = "on";
                app.Label11.Visible = "on";
                app.Label12.Visible = "on";
                app.Label13.Visible = "on";

                %Wheelbase lookup
                if app.CarDropDown.Value == "TY 20"
                    wheelbase = 1.580;
                elseif app.CarDropDown.Value == "TY 22"
                    wheelbase = 1.530;
                end
            
                %Front Wheel rotation outputs
                distance_origin_fw = 0.47;
                app.Label2.Text = num2str(distance_origin_fw);
                app.Label3.Text = "0";
                distance_ground_CenterOfWheel = 0.1723;
                app.Label4.Text = num2str(distance_ground_CenterOfWheel);

                app.Label5.Text = "0";
                app.Label6.Text = "1";
                app.Label7.Text = "0";
            
                %Rear Wheel rotation outputs
                distance_origin_rw = distance_origin_fw + wheelbase;
                app.Label8.Text = num2str(distance_origin_rw);
                app.Label9.Text = "0";
                app.Label10.Text = num2str(distance_ground_CenterOfWheel);

                app.Label11.Text = "0";
                app.Label12.Text = "1";
                app.Label13.Text = "0";
            end

            if app.airangleEditField.Value ~= 0
                app.Label14.Visible = "on";
                app.Label15.Visible = "on";

                x_part = cosd(app.airangleEditField.Value) * velocity;
                y_part = sind(app.airangleEditField.Value) * velocity;

                app.Label14.Text = num2str(round(x_part,2));
                app.Label15.Text = num2str(round(y_part,2));
            end
        end

        % Button pushed function: BacktomainmenuButton
        function BacktomainmenuButtonPushed(app, event)
            interface
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.calculatesetupButton = uibutton(app.UIFigure, 'push');
            app.calculatesetupButton.ButtonPushedFcn = createCallbackFcn(app, @calculatesetupButtonPushed, true);
            app.calculatesetupButton.Text = 'calculate setup';
            app.WheelangularspeedradsLabel = uilabel(app.UIFigure);
            app.WheelangularspeedradsLabel.Text = 'Wheel angular speed [rad/s]:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.WheelsLabel = uilabel(app.UIFigure);
            app.WheelsLabel.Text = 'Wheels';
            app.FrontwheelLabel = uilabel(app.UIFigure);
            app.FrontwheelLabel.Text = 'Frontwheel';
            app.RotationAxisOriginLabel = uilabel(app.UIFigure);
            app.RotationAxisOriginLabel.Text = 'Rotation-Axis Origin';
            app.BacktomainmenuButton = uibutton(app.UIFigure, 'push');
            app.BacktomainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomainmenuButtonPushed, true);
            app.BacktomainmenuButton.Text = 'Back to main menu';
            app.xmLabel = uilabel(app.UIFigure);
            app.xmLabel.Text = 'x [m]:';
            app.ymLabel = uilabel(app.UIFigure);
            app.ymLabel.Text = 'y [m]:';
            app.zmLabel = uilabel(app.UIFigure);
            app.zmLabel.Text = 'z [m]:';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Visible = 'off';
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Visible = 'off';
            app.Label4.Text = 'Label4';
            app.RotationAxisDirectionLabel = uilabel(app.UIFigure);
            app.RotationAxisDirectionLabel.Text = 'Rotation-Axis Direction';
            app.xmLabel_2 = uilabel(app.UIFigure);
            app.xmLabel_2.Text = 'x [m]:';
            app.ymLabel_2 = uilabel(app.UIFigure);
            app.ymLabel_2.Text = 'y [m]:';
            app.zmLabel_2 = uilabel(app.UIFigure);
            app.zmLabel_2.Text = 'z [m]:';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Visible = 'off';
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Visible = 'off';
            app.Label6.Text = 'Label6';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Visible = 'off';
            app.Label7.Text = 'Label7';
            app.RearwheelLabel = uilabel(app.UIFigure);
            app.RearwheelLabel.Text = 'Rearwheel';
            app.RotationAxisOriginLabel_2 = uilabel(app.UIFigure);
            app.RotationAxisOriginLabel_2.Text = 'Rotation-Axis Origin';
            app.xmLabel_3 = uilabel(app.UIFigure);
            app.xmLabel_3.Text = 'x [m]:';
            app.ymLabel_3 = uilabel(app.UIFigure);
            app.ymLabel_3.Text = 'y [m]:';
            app.zmLabel_3 = uilabel(app.UIFigure);
            app.zmLabel_3.Text = 'z [m]:';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Visible = 'off';
            app.Label8.Text = 'Label8';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Visible = 'off';
            app.Label9.Text = 'Label9';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Visible = 'off';
            app.Label10.Text = 'Label10';
            app.RotationAxisDirectionLabel_2 = uilabel(app.UIFigure);
            app.RotationAxisDirectionLabel_2.Text = 'Rotation-Axis Direction';
            app.xmLabel_4 = uilabel(app.UIFigure);
            app.xmLabel_4.Text = 'x [m]:';
            app.ymLabel_4 = uilabel(app.UIFigure);
            app.ymLabel_4.Text = 'y [m]:';
            app.zmLabel_4 = uilabel(app.UIFigure);
            app.zmLabel_4.Text = 'z [m]:';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Visible = 'off';
            app.Label11.Text = 'Label11';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Visible = 'off';
            app.Label12.Text = 'Label12';
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Visible = 'off';
            app.Label13.Text = 'Label13';
            app.CarDropDownLabel = uilabel(app.UIFigure);
            app.CarDropDownLabel.Text = 'Car:';
            app.CarDropDown = uidropdown(app.UIFigure);
            app.CarDropDown.Items = {'<select car>', 'TY 20', 'TY 22'};
            app.CarDropDown.Value = '<select car>';
            app.airangleEditFieldLabel = uilabel(app.UIFigure);
            app.airangleEditFieldLabel.Text = 'air angle [°]:';
            app.airangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.carvelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.carvelocitymsEditFieldLabel.Text = 'car velocity [m/s]:';
            app.carvelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InletInputsLabel = uilabel(app.UIFigure);
            app.InletInputsLabel.Text = 'Inlet Inputs';
            app.xcomponentairmsLabel = uilabel(app.UIFigure);
            app.xcomponentairmsLabel.Text = 'x-component air [m/s]:';
            app.ycomponentairmsLabel = uilabel(app.UIFigure);
            app.ycomponentairmsLabel.Text = 'y-component air [m/s]:';
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Visible = 'off';
            app.Label14.Text = 'Label14';
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Visible = 'off';
            app.Label15.Text = 'Label15';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yaw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end